classdef M3AnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        TESTButton                 matlab.ui.control.Button
        ClearTableButton           matlab.ui.control.Button
        AddRowButton               matlab.ui.control.Button
        TimeScoreTable             matlab.ui.control.Table
        KnownScoreEditField        matlab.ui.control.NumericEditField
        KnownScoreEditFieldLabel   matlab.ui.control.Label
        KnownTimeEditField         matlab.ui.control.NumericEditField
        KnownTimeEditFieldLabel    matlab.ui.control.Label
        KnownLengthEditField       matlab.ui.control.NumericEditField
        KnownLengthEditFieldLabel  matlab.ui.control.Label
        M3AnalysisToolLabel        matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        WarningMsg                 matlab.ui.control.Label
        TargetTime                 matlab.ui.control.Label
        TargetLength               matlab.ui.control.Label
        TargetSpeed                matlab.ui.control.Label
        TimeLabel                  matlab.ui.control.Label
        LengthLabel                matlab.ui.control.Label
        SpeedLabel                 matlab.ui.control.Label
        TargetsLabel               matlab.ui.control.Label
        LengthWeightSlider         matlab.ui.control.Slider
        LengthWeightLabel          matlab.ui.control.Label
        SpeedWeightSlider          matlab.ui.control.Slider
        SpeedWeightLabel           matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)

        function inverseList = inverse(~, list)
        % Turns a list of numbers into a list of their inverses
            inverseList = zeros(1, length(list));
            for i = 1:length(list)
                inverseList(i) = 1 / list(i);
            end
        end
        
        function maxList = maxPair(~, cellArr)
        % Returns the pair with the maximum ratio
            maxList = cellArr{1};
            maxRatio = maxList(1) / maxList(2);
            for i = 1:length(cellArr)
                tempList = cellArr{i};
                tempRatio = tempList(1) / tempList(2);
                if tempRatio > maxRatio
                    maxList = tempList;
                    maxRatio = tempRatio;
                end
            end
        end

        function cellArr = rmvElement(~, cellArr, element)
        % Returns a cell array with the specified element removed
            index = find(cellfun(@(e) isequal(e, element), cellArr));
            if ~isempty(index)
                cellArr(index) = [];
            end
        end

        function numList = strToNums(~, str)
        % Turns a comma-seperated string of numbers into a list of doubles
            numStrs = split(str, ",");
            numList = str2double(numStrs);
        end
        
        function pairList = pair(~, list1, list2)
        %   Turns 2 lists into 1 list of index pairs
        %   e.g. pair([1, 2, 3], [4, 5, 6]) = {[1, 4], [2, 5], [3, 6]}
            if ~isvector(list1) || ~isvector(list2) || numel(list1) ~= numel(list2)
                error("Inputs must be vectors of the same length");
            end
            
            pairList = cell(1, length(list1));
            for i = 1:length(list1)
                pairList{i} = [list1(i), list2(i)];
            end
        end
        
        function [list1, list2] = unpair(~, pairList)
        % Turns a list of index pairs into 2 separate lists
        % e.g. unpair({[1, 4], [2, 5], [3, 6]}) = {[1, 2, 3], [4, 5, 6]}
            n = length(pairList);
            list1 = zeros(1, n);
            list2 = zeros(1, n);
            for i = 1:n
                pair = pairList{i};
                list1(i) = pair(1);
                list2(i) = pair(2);
            end
        end

        function inferredList = inferLengths(~, timeScoreList, knownCombo)
        %   Returns a list of inferred antenna lengths
        %   Input should be a list of time-score pairs and a known length-time-score combo
            if ~isvector(knownCombo) || numel(knownCombo) ~= 3
                error("Invalid known variable(s)")
            end
        
            knownTime = knownCombo(1);
            knownLength = knownCombo(2);
            knownScore = knownCombo(3);
        
            inferredList = zeros(1, length(timeScoreList));
            for i = 1:length(timeScoreList)
                tempPair = timeScoreList{i};
                tempTime = tempPair(1);
                tempScore = tempPair(2);
                tempLength = (tempScore - 2) / (knownScore - 2) * (knownLength / knownTime) * tempTime;
                inferredList(i) = tempLength;
            end
        end      

        function updatePlot(app)
            if app.KnownTimeEditField.Value <= 0 || app.KnownLengthEditField.Value <= 0 || app.KnownScoreEditField.Value < 2 ...
               || isempty(app.TimeScoreTable)
                app.WarningMsg.Text = "Error: Not all values set";
                return
            end

            knownCombo = [app.KnownTimeEditField.Value, app.KnownLengthEditField.Value, app.KnownScoreEditField.Value];
            knownTime = knownCombo(1);
            knownSpeed = 1 / knownTime;
            knownLength = knownCombo(2);
            knownScore = knownCombo(3);

            times = strToNums(app, strjoin(string(app.TimeScoreTable.Data(:, 1)), ","));
            scores = strToNums(app, strjoin(string(app.TimeScoreTable.Data(:, 2)), ","));

            if length(times) ~= length(scores)
                app.WarningMsg.Text = "Warning: Different number of time and score entries, plot not updated";
                return
            else
                app.WarningMsg.Text = "";
            end

            timeScoreList = pair(app, times, scores);
            lengths = inferLengths(app, timeScoreList, knownCombo);
        
            bestPair = maxPair(app, pair(app, lengths, times));
            bestTime = bestPair(2);
            bestSpeed = 1 / bestTime;
            bestLength = bestPair(1);
            bestRatio = bestLength / bestTime;

            % Remove known time-score pair if it is in the input
            timeScoreList = rmvElement(app, timeScoreList, [knownTime, knownScore]);
            % Removes best time-score pair
            timeScoreList = rmvElement(app, timeScoreList, [bestTime, 3]);
            [times, ~] = unpair(app, timeScoreList);

            speeds = inverse(app, times);
            lengths = inferLengths(app, timeScoreList, knownCombo);

            % Plot competitor performance
            plot(app.UIAxes, speeds, lengths, "o", "MarkerSize", 8, "MarkerFaceColor", "black");

            % Plot our own performance
            hold(app.UIAxes, "on");
            plot(app.UIAxes, knownSpeed, knownLength, "o", "MarkerSize", 8, "MarkerFaceColor", "blue");

            % Plot the best performance
            hold(app.UIAxes, "on");
            plot(app.UIAxes, bestSpeed, bestLength, "o", "MarkerSize", 8, "MarkerFaceColor", "green");

            % Plot target performance
            ratioDiff = bestRatio / (knownLength / knownTime);
            lengthWeight = app.LengthWeightSlider.Value;
            targetLength = (ratioDiff ^ (lengthWeight / 100)) * knownLength;
            targetSpeed = ((ratioDiff ^ ((100 - lengthWeight) / 100)) * knownSpeed);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, targetSpeed, targetLength, "o", "MarkerSize", 8, "MarkerFaceColor", "red");
            hold(app.UIAxes, "off");

            % Update target values]
            app.TargetSpeed.Text = strcat(string(round(targetSpeed / knownSpeed, 2)), "x");
            app.TargetTime.Text = string(round(1 / targetSpeed, 2));
            app.TargetLength.Text = string(round(targetLength, 2));

            % Set plot boundaries
            xMin = min(speeds) * 0.80;
            xMax = max([speeds, targetSpeed]) * 1.20;
            xlim(app.UIAxes, [xMin, xMax]);
            yMin = min(lengths) * 0.80;
            yMax = max([lengths, targetLength]) * 1.20;
            ylim(app.UIAxes, [yMin, yMax]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SpeedWeightSlider
        function updatedSpeedWeight(app, event)
            updatePlot(app);
        end

        % Value changed function: LengthWeightSlider
        function updatedLengthWeight(app, event)
            updatePlot(app);
        end

        % Callback function: not associated with a component
        function updatedTimes(app, event)
            updatePlot(app);
        end

        % Callback function: not associated with a component
        function updatedScores(app, event)
            updatePlot(app);
        end

        % Value changing function: SpeedWeightSlider
        function updatingSpeedWeight(app, event)
            changingValue = event.Value;
            app.LengthWeightSlider.Value = 100 - changingValue;
        end

        % Value changing function: LengthWeightSlider
        function updatingLengthWeight(app, event)
            changingValue = event.Value;
            app.SpeedWeightSlider.Value = 100 - changingValue;
        end

        % Value changed function: KnownTimeEditField
        function updatedKnownTime(app, event)
            updatePlot(app);
        end

        % Value changed function: KnownLengthEditField
        function updatedKnownLength(app, event)
            updatePlot(app);
        end

        % Value changed function: KnownScoreEditField
        function updatedKnownScore(app, event)
            updatePlot(app);
        end

        % Button pushed function: AddRowButton
        function addRow(app, event)
            app.TimeScoreTable.Data = [app.TimeScoreTable.Data; [0, 0]];
            updatePlot(app);
        end

        % Cell edit callback: TimeScoreTable
        function updatedTable(app, event)
            updatePlot(app);
        end

        % Button pushed function: ClearTableButton
        function clearTable(app, event)
            app.TimeScoreTable.Data = [];
        end

        % Button pushed function: TESTButton
        function testFunction(app, event)
            times = [90, 120, 111, 132, 107, 98, 89, 96, 105];
            scores = [2.71, 2.88, 2.99, 2.74, 2.36, 2.78, 3, 2.97, 2.93];
            app.TimeScoreTable.Data = [times', scores'];
            app.KnownTimeEditField.Value = 132;
            app.KnownLengthEditField.Value = 23;
            app.KnownScoreEditField.Value = 2.74;
            updatePlot(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {494, 494};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {230, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.M3AnalysisToolLabel = uilabel(app.GridLayout3);
            app.M3AnalysisToolLabel.Text = 'M3 Analysis Tool';
            app.KnownLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownLengthEditFieldLabel.Text = 'Known Length';
            app.KnownLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownLengthEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownLength, true);
            app.KnownTimeEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownTimeEditFieldLabel.Text = 'Known Time';
            app.KnownTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownTimeEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownTime, true);
            app.KnownScoreEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownScoreEditFieldLabel.Text = 'Known Score';
            app.KnownScoreEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownScoreEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownScore, true);
            app.TimeScoreTable = uitable(app.GridLayout3);
            app.TimeScoreTable.ColumnName = {'Time'; 'Score'};
            app.TimeScoreTable.RowName = {};
            app.TimeScoreTable.ColumnEditable = true;
            app.TimeScoreTable.CellEditCallback = createCallbackFcn(app, @updatedTable, true);
            app.AddRowButton = uibutton(app.GridLayout3, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @addRow, true);
            app.AddRowButton.Text = 'Add Row';
            app.ClearTableButton = uibutton(app.GridLayout3, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @clearTable, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.TESTButton = uibutton(app.GridLayout3, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @testFunction, true);
            app.TESTButton.Text = 'TEST';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Aircraft Performance')
            xlabel(app.UIAxes, 'Speed (1 / Time)')
            ylabel(app.UIAxes, 'Length')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'JAD Logo.png';
            app.SpeedWeightLabel = uilabel(app.GridLayout2);
            app.SpeedWeightLabel.Text = {'Speed'; 'Weight'};
            app.SpeedWeightSlider = uislider(app.GridLayout2);
            app.SpeedWeightSlider.ValueChangedFcn = createCallbackFcn(app, @updatedSpeedWeight, true);
            app.SpeedWeightSlider.ValueChangingFcn = createCallbackFcn(app, @updatingSpeedWeight, true);
            app.SpeedWeightSlider.Value = 50;
            app.LengthWeightLabel = uilabel(app.GridLayout2);
            app.LengthWeightLabel.Text = {'Length'; 'Weight'};
            app.LengthWeightSlider = uislider(app.GridLayout2);
            app.LengthWeightSlider.ValueChangedFcn = createCallbackFcn(app, @updatedLengthWeight, true);
            app.LengthWeightSlider.ValueChangingFcn = createCallbackFcn(app, @updatingLengthWeight, true);
            app.LengthWeightSlider.Value = 50;
            app.TargetsLabel = uilabel(app.GridLayout2);
            app.TargetsLabel.Text = 'Targets';
            app.SpeedLabel = uilabel(app.GridLayout2);
            app.SpeedLabel.Text = 'Speed';
            app.LengthLabel = uilabel(app.GridLayout2);
            app.LengthLabel.Text = 'Length';
            app.TimeLabel = uilabel(app.GridLayout2);
            app.TimeLabel.Text = 'Time';
            app.TargetSpeed = uilabel(app.GridLayout2);
            app.TargetSpeed.Text = 'N/A';
            app.TargetLength = uilabel(app.GridLayout2);
            app.TargetLength.Text = 'N/A';
            app.TargetTime = uilabel(app.GridLayout2);
            app.TargetTime.Text = 'N/A';
            app.WarningMsg = uilabel(app.GridLayout2);
            app.WarningMsg.Text = 'Error: Not all values set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M3AnalysisTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end